classdef Robot_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AngleasdegreeLabel    matlab.ui.control.Label
        Pos_zEditField        matlab.ui.control.EditField
        Pos_zEditFieldLabel   matlab.ui.control.Label
        Pos_yEditField        matlab.ui.control.EditField
        Pos_yEditFieldLabel   matlab.ui.control.Label
        Pos_xEditField        matlab.ui.control.EditField
        Pos_xEditFieldLabel   matlab.ui.control.Label
        Theta1EditField       matlab.ui.control.EditField
        Theta1EditFieldLabel  matlab.ui.control.Label
        Theta2EditField       matlab.ui.control.EditField
        Theta2EditFieldLabel  matlab.ui.control.Label
        Theta3EditField       matlab.ui.control.EditField
        Theta3EditFieldLabel  matlab.ui.control.Label
        ForwardButton         matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            %% Get data from text box (This is ARTICULATED ARM. so the input is 3 thetas)
            theta1=str2double(app.Theta1EditField.Value)*pi/180;
            theta2=str2double(app.Theta2EditField.Value)*pi/180;
            theta3=str2double(app.Theta3EditField.Value)*pi/180;
            
            %% declare links constain
            d1=15;
            d2=0;
            d3=0;
            a1=0;
            a2=5;
            a3=5;
            alpha1=pi/2;
            alpha2=0;
            alpha3=0;
            
            %% create links matrix(Denavit-Hartenberg matrix)
            PO=[0;0;0;1];
            A0_1=Add_Link(theta1,d1,a1,alpha1);
            A1_2=Add_Link(theta2,d2,a2,alpha2);
            A2_3=Add_Link(theta3,d3,a3,alpha3);
            
            %% Calculate joints, end-effector
            P1=A0_1*PO;
            P2=A0_1*A1_2*PO;
            P3=A0_1*A1_2*A2_3*PO;
            
            %% Show end-effector point
            app.Pos_xEditField.Value=num2str(P3(1,1));
            app.Pos_yEditField.Value=num2str(P3(2,1));
            app.Pos_zEditField.Value=num2str(P3(3,1));
            
            %% Show robot on UI
            plot3(app.UIAxes,0,0,0,'o');
            grid(app.UIAxes,"on")
            hold(app.UIAxes,'on')
            line(app.UIAxes,[0,P1(1,1)],[0,P1(2,1)],[0,P1(3,1)],'color','red');
            plot3(app.UIAxes,P1(1,1),P1(2,1),P1(3,1),'or');
            line(app.UIAxes,[P1(1,1),P2(1,1)],[P1(2,1),P2(2,1)],[P1(3,1),P2(3,1)],'color','blue');
            plot3(app.UIAxes,P2(1,1),P2(2,1),P2(3,1),'or');
            line(app.UIAxes,[P2(1,1),P3(1,1)],[P2(2,1),P3(2,1)],[P2(3,1),P3(3,1)],'color','green');
            plot3(app.UIAxes,P3(1,1),P3(2,1),P3(3,1),'or');
            hold(app.UIAxes,'off')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot visualize')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Tag = 'btn_forward';
            app.ForwardButton.Text = 'Forward';
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'text');
            app.Theta3EditField.Tag = 'Theta_3_txt';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'text');
            app.Theta2EditField.Tag = 'Theta_2_txt';
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'text');
            app.Theta1EditField.Tag = 'Theta_1_txt';
            app.Pos_xEditFieldLabel = uilabel(app.UIFigure);
            app.Pos_xEditFieldLabel.Tag = 'Pos_x_txt';
            app.Pos_xEditFieldLabel.Text = 'Pos_x';
            app.Pos_xEditField = uieditfield(app.UIFigure, 'text');
            app.Pos_xEditField.Tag = 'Theta_1_txt';
            app.Pos_xEditField.Editable = 'off';
            app.Pos_yEditFieldLabel = uilabel(app.UIFigure);
            app.Pos_yEditFieldLabel.Tag = 'Pos_y_txt';
            app.Pos_yEditFieldLabel.Text = 'Pos_y';
            app.Pos_yEditField = uieditfield(app.UIFigure, 'text');
            app.Pos_yEditField.Tag = 'Theta_1_txt';
            app.Pos_yEditField.Editable = 'off';
            app.Pos_zEditFieldLabel = uilabel(app.UIFigure);
            app.Pos_zEditFieldLabel.Tag = 'Pos_z_txt';
            app.Pos_zEditFieldLabel.Text = 'Pos_z';
            app.Pos_zEditField = uieditfield(app.UIFigure, 'text');
            app.Pos_zEditField.Tag = 'Theta_1_txt';
            app.Pos_zEditField.Editable = 'off';
            app.AngleasdegreeLabel = uilabel(app.UIFigure);
            app.AngleasdegreeLabel.Text = 'Angle as degree';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end